classdef rinsing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        AbnormalmotorRpmdatacollectedforfurtheranalysisLabel  matlab.ui.control.Label
        Image5                      matlab.ui.control.Image
        Image4                      matlab.ui.control.Image
        Image3                      matlab.ui.control.Image
        AnhaltenStopButton          matlab.ui.control.Button
        MotordrehzahlMotorRPMGauge  matlab.ui.control.Gauge
        MotordrehzahlMotorRPMGaugeLabel  matlab.ui.control.Label
        TemperaturTemperatureGauge  matlab.ui.control.Gauge
        TemperaturTemperatureGaugeLabel  matlab.ui.control.Label
        AnfangenStartButton         matlab.ui.control.Button
        WasserstandWaterLevelGauge  matlab.ui.control.Gauge
        WasserstandWaterLevelGaugeLabel  matlab.ui.control.Label
        SplungRinsingLabel          matlab.ui.control.Label
        Image2                      matlab.ui.control.Image
        Image                       matlab.ui.control.Image
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end


        properties (Access = private)
    SimulinkModelName = 'WaterFlowSubsystem';
    SimulinkModelName_temp = 'Temperature_control';
    SimulinkModelName_speed = 'BLDC_motor';
    Run_flag = 1 ;
    gauage_flag  = 1 ;
    StopSimulation = false; % Flag to stop simulation
end % Description
        methods (Access = private)
        
        function updateGauges(app)
    while ~app.StopSimulation
        try

            if app.gauage_flag == 1
            % Fetch signals from Simulink
            Level = get_param([app.SimulinkModelName '/WaterFlowSubsystem/WaterLevel'], 'RuntimeObject').OutputPort(1).Data;
            % Update gauges
            app.WasserstandWaterLevelGauge.Value = Level;
            pause(0.1); % Small delay for real-time updates
            drawnow;
            elseif app.gauage_flag == 2
                % Fetch signals from Simulink
            Level = get_param([app.SimulinkModelName_temp '/Sum1'], 'RuntimeObject').OutputPort(1).Data;
            % Update gauges
            app.TemperaturTemperatureGauge.Value = Level;
            pause(0.1); % Small delay for real-time updates
            drawnow;
            elseif app.gauage_flag == 3
                % Fetch signals from Simulink
            Level = get_param([app.SimulinkModelName_speed '/Sum1'], 'RuntimeObject').OutputPort(1).Data;
            % Update gauges
            app.MotordrehzahlMotorRPMGauge.Value = Level;
            pause(0.1); % Small delay for real-time updates
            drawnow;
            end


        catch ME
            disp(ME.message);
            break;
        end
    end     
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function SimulationControlsSizeChanged(app, event)

            
        end

        % Button pushed function: AnfangenStartButton
        function AnfangenStartButtonPushed(app, event)
            pred_analy_flag = evalin('base', 'pred_analy_flag');
            if pred_analy_flag == 1
                speed_rate = 0 ;
            else
                   speed_rate_check;
                 speed_rate = evalin('base', 'speed_rate');
                 
            end
           
            if speed_rate == 1
             app.Image5.Visible = 'on';
             app.AbnormalmotorRpmdatacollectedforfurtheranalysisLabel.Visible = 'on';
             pause(15);
             app.Image5.Visible = 'off';
             app.AbnormalmotorRpmdatacollectedforfurtheranalysisLabel.Visible = 'off';
              assignin('base', 'pred_analy_flag', 1);
            end
            app.Run_flag = 1;
            app.SplungRinsingLabel.Text = 'Spülung/Rinsing';
    if app.Run_flag == 1
    app.StopSimulation = false;
    assignin('base', 'Water_switch', 1);
    assignin('base', 'N_l_limit', 15);
    assignin('base', 'N_u_limit', 30);
    set_param(app.SimulinkModelName, 'SimulationCommand', 'start');
    app.AnfangenStartButton.Text = 'ausgeführt/Running...';
    app.AnfangenStartButton.Enable = 'off';
    app.updateGauges();
    else
        app.SplungRinsingLabel.Text = 'ANGEHALTEN.. Spülung/STOPPED..Rinsing';
    end
    if app.Run_flag == 1
    app.gauage_flag = 2;
    assignin('base', 'Temperature_max', 60);
    set_param(app.SimulinkModelName_temp, 'SimulationCommand', 'start');
    app.updateGauges();
    else
        app.SplungRinsingLabel.Text = 'ANGEHALTEN.. Spülung/STOPPED..Rinsing';
    end
    if app.Run_flag == 1
    app.gauage_flag = 3;
     assignin('base', 'set_time', 10);
     assignin('base', 'set_speed', 600);
     set_param(app.SimulinkModelName_speed, 'SimulationCommand', 'start');
    app.updateGauges();
    app.SplungRinsingLabel.Text = 'Spülung...abgeschlossen/Rinsing Completed......';
     if pred_analy_flag == 1
     predective_maintenanace;
     app.SplungRinsingLabel.Text = 'Spülung...abgeschlossen......Ausführen einer Analyse für einen möglichen Ausfall/Rinsing Completed......Running Analysis for potential failure';
     else
     app.SplungRinsingLabel.Text = 'Spülung...abgeschlossen/Rinsing Completed......';
    
     end
    app.AnfangenStartButton.Text = 'Anfangen/Start';
    app.AnfangenStartButton.Enable = 'on';
  pause(10);
  workspaceString = evalin('base', 'messageFrompred'); 

   uialert(app.UIFigure, ...  % Reference to the main app window (UIFigure)
            workspaceString, ...         % The message to display in the alert
            'Alert', ...   % Title of the alert box
            'Icon', 'info', ...  % Icon type (options: 'info', 'warning', 'error', etc.)
            'CloseFcn', 'on'); % Show the close button on the alert
   pause(10);
     app.UIFigure.Visible = 'off';
   home_pageApp = display_of_controls();
   clear('all');
 
      else
        app.SplungRinsingLabel.Text = 'ANGEHALTEN.. Spülung/STOPPED..Rinsing';
    end

        end

        % Button pushed function: AnhaltenStopButton
        function AnhaltenStopButtonPushed(app, event)
            if app.gauage_flag == 2
                set_param(app.SimulinkModelName_temp, 'SimulationCommand', 'stop');
                app.Run_flag = 0;
            elseif app.gauage_flag == 3
                 set_param(app.SimulinkModelName_speed, 'SimulationCommand', 'stop');
                 app.Run_flag = 0;
            else
                 set_param(app.SimulinkModelName, 'SimulationCommand', 'stop'); 
                 app.Run_flag = 0;
            end
            app.AnfangenStartButton.Text = 'Anfangen/Start';
            app.AnfangenStartButton.Enable = 'on';
        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
            secondApp = SecondApp();
        end

        % Callback function
        function Image4Clicked(app, event)
            soakingApp = soaking();
        end

        % Image clicked function: Image4
        function Image4Clicked2(app, event)
          washingApp = Washing();  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Controls_bg.jpg';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'rinsing.jpg');
            app.SplungRinsingLabel = uilabel(app.UIFigure);
            app.SplungRinsingLabel.Interpreter = 'html';
            app.SplungRinsingLabel.Text = 'Spülung/Rinsing';
            app.WasserstandWaterLevelGaugeLabel = uilabel(app.UIFigure);
            app.WasserstandWaterLevelGaugeLabel.Text = 'Wasserstand/Water Level';
            app.WasserstandWaterLevelGauge = uigauge(app.UIFigure, 'circular');
            app.WasserstandWaterLevelGauge.Limits = [0 40];
            app.AnfangenStartButton = uibutton(app.UIFigure, 'push');
            app.AnfangenStartButton.ButtonPushedFcn = createCallbackFcn(app, @AnfangenStartButtonPushed, true);
            app.AnfangenStartButton.Interpreter = 'html';
            app.AnfangenStartButton.Text = 'Anfangen/Start';
            app.TemperaturTemperatureGaugeLabel = uilabel(app.UIFigure);
            app.TemperaturTemperatureGaugeLabel.Text = 'Temperatur/Temperature';
            app.TemperaturTemperatureGauge = uigauge(app.UIFigure, 'circular');
            app.MotordrehzahlMotorRPMGaugeLabel = uilabel(app.UIFigure);
            app.MotordrehzahlMotorRPMGaugeLabel.Text = 'Motordrehzahl/Motor RPM';
            app.MotordrehzahlMotorRPMGauge = uigauge(app.UIFigure, 'circular');
            app.MotordrehzahlMotorRPMGauge.Limits = [0 500];
            app.AnhaltenStopButton = uibutton(app.UIFigure, 'push');
            app.AnhaltenStopButton.ButtonPushedFcn = createCallbackFcn(app, @AnhaltenStopButtonPushed, true);
            app.AnhaltenStopButton.Interpreter = 'html';
            app.AnhaltenStopButton.Text = 'Anhalten/Stop';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'home.jpg');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @Image4Clicked2, true);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'back.jpg');
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.Visible = 'off';
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'Yellow-Warning-Transparent.png');
            app.AbnormalmotorRpmdatacollectedforfurtheranalysisLabel = uilabel(app.UIFigure);
            app.AbnormalmotorRpmdatacollectedforfurtheranalysisLabel.Visible = 'off';
            app.AbnormalmotorRpmdatacollectedforfurtheranalysisLabel.Interpreter = 'html';
            app.AbnormalmotorRpmdatacollectedforfurtheranalysisLabel.Text = 'Abnorme Motordrehzahlen.. Daten, die für die weitere Analyse gesammelt wurden/Abnormal motor Rpm..data collected for further analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rinsing

            % Associate the Simulink Model
            app.Simulation = simulation('BLDC_motor');

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end