classdef soaking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Image3                      matlab.ui.control.Image
        AnhaltenStopButton          matlab.ui.control.Button
        MotordrehzahlMotorRPMGauge  matlab.ui.control.Gauge
        MotordrehzahlMotorRPMGaugeLabel  matlab.ui.control.Label
        TemperaturTemperatureGauge  matlab.ui.control.Gauge
        TemperaturTemperatureGaugeLabel  matlab.ui.control.Label
        AnfangenStartButton         matlab.ui.control.Button
        WasserstandWaterLevelGauge  matlab.ui.control.Gauge
        WasserstandWaterLevelGaugeLabel  matlab.ui.control.Label
        EINWEICHENSOAKINGLabel      matlab.ui.control.Label
        Image2                      matlab.ui.control.Image
        Image                       matlab.ui.control.Image
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end


        properties (Access = private)
    SimulinkModelName = 'WaterFlowSubsystem';
    SimulinkModelName_temp = 'Temperature_control';
    SimulinkModelName_speed = 'BLDC_motor';
    Run_flag = 1 ;
    gauage_flag  = 1 ;
    StopSimulation = false; % Flag to stop simulation
end % Description
        methods (Access = private)
        
        function updateGauges(app)
    while ~app.StopSimulation
        try

            if app.gauage_flag == 1
            % Fetch signals from Simulink
            Level = get_param([app.SimulinkModelName '/WaterFlowSubsystem/WaterLevel'], 'RuntimeObject').OutputPort(1).Data;
            % Update gauges
            app.WasserstandWaterLevelGauge.Value = Level;
            pause(0.1); % Small delay for real-time updates
            drawnow;
            elseif app.gauage_flag == 2
                % Fetch signals from Simulink
            Level = get_param([app.SimulinkModelName_temp '/Sum1'], 'RuntimeObject').OutputPort(1).Data;
            % Update gauges
            app.TemperaturTemperatureGauge.Value = Level;
            pause(0.1); % Small delay for real-time updates
            drawnow;
            elseif app.gauage_flag == 3
                % Fetch signals from Simulink
            Level = get_param([app.SimulinkModelName_speed '/Sum1'], 'RuntimeObject').OutputPort(1).Data;
            % Update gauges
            app.MotordrehzahlMotorRPMGauge.Value = Level;
            pause(0.1); % Small delay for real-time updates
            drawnow;
            end


        catch ME
            disp(ME.message);
            break;
        end
    end     
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function SimulationControlsSizeChanged(app, event)

            
        end

        % Button pushed function: AnfangenStartButton
        function AnfangenStartButtonPushed(app, event)
            app.Run_flag = 1;
            app.EINWEICHENSOAKINGLabel.Text = 'EINWEICHEN/SOAKING';
    if app.Run_flag == 1
    app.StopSimulation = false;
    assignin('base', 'Water_switch', 1);
    assignin('base', 'N_l_limit', 5);
    assignin('base', 'N_u_limit', 10);
    set_param(app.SimulinkModelName, 'SimulationCommand', 'start');
    app.AnfangenStartButton.Text = 'ausgeführt/Running...';
    app.AnfangenStartButton.Enable = 'off';
    app.updateGauges();
    else
        app.EINWEICHENSOAKINGLabel.Text = 'ANGEHALTEN.. EINWEICHEN/STOPPED..SOAKING';
    end
    if app.Run_flag == 1
    app.gauage_flag = 2;
    assignin('base', 'Temperature_max', 30);
    set_param(app.SimulinkModelName_temp, 'SimulationCommand', 'start');
    app.updateGauges();
    else
        app.EINWEICHENSOAKINGLabel.Text = 'ANGEHALTEN.. EINWEICHEN/STOPPED..SOAKING';
    end
    if app.Run_flag == 1
    app.gauage_flag = 3;
     assignin('base', 'set_time', 10);
     assignin('base', 'set_speed', 100);
     set_param(app.SimulinkModelName_speed, 'SimulationCommand', 'start');
    app.updateGauges();
    app.AnfangenStartButton.Text = 'Anfangen/Start';
    app.AnfangenStartButton.Enable = 'on';
  app.EINWEICHENSOAKINGLabel.Text = 'Eingeweicht...abgeschlossen/Soaking Completed......';
  pause(10);
              washingApp = Washing();
  app.UIFigure.Visible = 'off';
      else
        app.EINWEICHENSOAKINGLabel.Text = 'ANGEHALTEN.. EINWEICHEN/STOPPED..SOAKING';
    end

        end

        % Button pushed function: AnhaltenStopButton
        function AnhaltenStopButtonPushed(app, event)
            if app.gauage_flag == 2
                set_param(app.SimulinkModelName_temp, 'SimulationCommand', 'stop');
                app.Run_flag = 0;
            elseif app.gauage_flag == 3
                 set_param(app.SimulinkModelName_speed, 'SimulationCommand', 'stop');
                 app.Run_flag = 0;
            else
                 set_param(app.SimulinkModelName, 'SimulationCommand', 'stop'); 
                 app.Run_flag = 0;
            end
            app.AnfangenStartButton.Text = 'Anfangen/Start';
            app.AnfangenStartButton.Enable = 'on';
        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
            secondApp = SecondApp();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Controls_bg.jpg';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'soaking.jpeg';
            app.EINWEICHENSOAKINGLabel = uilabel(app.UIFigure);
            app.EINWEICHENSOAKINGLabel.Interpreter = 'html';
            app.EINWEICHENSOAKINGLabel.Text = 'EINWEICHEN/SOAKING';
            app.WasserstandWaterLevelGaugeLabel = uilabel(app.UIFigure);
            app.WasserstandWaterLevelGaugeLabel.Text = 'Wasserstand/Water Level';
            app.WasserstandWaterLevelGauge = uigauge(app.UIFigure, 'circular');
            app.WasserstandWaterLevelGauge.Limits = [0 40];
            app.AnfangenStartButton = uibutton(app.UIFigure, 'push');
            app.AnfangenStartButton.ButtonPushedFcn = createCallbackFcn(app, @AnfangenStartButtonPushed, true);
            app.AnfangenStartButton.Interpreter = 'html';
            app.AnfangenStartButton.Text = 'Anfangen/Start';
            app.TemperaturTemperatureGaugeLabel = uilabel(app.UIFigure);
            app.TemperaturTemperatureGaugeLabel.Text = 'Temperatur/Temperature';
            app.TemperaturTemperatureGauge = uigauge(app.UIFigure, 'circular');
            app.MotordrehzahlMotorRPMGaugeLabel = uilabel(app.UIFigure);
            app.MotordrehzahlMotorRPMGaugeLabel.Text = 'Motordrehzahl/Motor RPM';
            app.MotordrehzahlMotorRPMGauge = uigauge(app.UIFigure, 'circular');
            app.AnhaltenStopButton = uibutton(app.UIFigure, 'push');
            app.AnhaltenStopButton.ButtonPushedFcn = createCallbackFcn(app, @AnhaltenStopButtonPushed, true);
            app.AnhaltenStopButton.Interpreter = 'html';
            app.AnhaltenStopButton.Text = 'Anhalten/Stop';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'home.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = soaking

            % Associate the Simulink Model
            app.Simulation = simulation('BLDC_motor');

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end